classdef exampleButtonTestingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        StartButton    matlab.ui.control.Button
        AbortButton    matlab.ui.control.Button
        StatusUITable  matlab.ui.control.Table
    end

    properties (Access = public)
        mainClass (1, 1) MainClass % Description
    end
    
    methods (Access = private)
        
        function stateChanged(app)
            if ismember(app.mainClass.state, {'Not Started', 'Aborted', 'Completed'})
                app.AbortButton.Enable = false;
                app.StartButton.Enable = true;
            elseif ismember(app.mainClass.state, {'Running'})
                app.AbortButton.Enable = true;
                app.StartButton.Enable = false;
            end
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.mainClass = MainClass();
            app.StatusUITableDisplayDataChanged();
            addlistener(app.mainClass, 'statusMessagesUpdated', @(~,~) app.StatusUITableDisplayDataChanged);
            app.stateChanged();
            addlistener(app.mainClass, 'stateUpdated', @(~,~) app.stateChanged);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.mainClass.run();
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.mainClass.abort();
        end

        % Display data changed function: StatusUITable
        function StatusUITableDisplayDataChanged(app, event)
            app.StatusUITable.Data = app.mainClass.statusMessages;
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AbortButton = uibutton(app.UIFigure, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Enable = 'off';
            app.AbortButton.Text = 'Abort';
            app.StatusUITable = uitable(app.UIFigure);
            app.StatusUITable.ColumnName = {'Status Messages'};
            app.StatusUITable.RowName = {};
            app.StatusUITable.DisplayDataChangedFcn = createCallbackFcn(app, @StatusUITableDisplayDataChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exampleButtonTestingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end